classdef Runcompare < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        ListListBox               matlab.ui.control.ListBox
        RectangleSelectionButton  matlab.ui.control.Button
        FreeselectionButton       matlab.ui.control.Button
        CorrespondingdistributionButton  matlab.ui.control.Button
        AveragespectraofthechosenareaLabel  matlab.ui.control.Label
        EnterButton               matlab.ui.control.Button
        RetainEditField           matlab.ui.control.EditField
        NoEditField               matlab.ui.control.EditField
        VSNoEditField             matlab.ui.control.EditField
        CompareButton             matlab.ui.control.Button
        NameLabel                 matlab.ui.control.Label
        Label                     matlab.ui.control.Label
        NoLabel                   matlab.ui.control.Label
        VSNoLabel                 matlab.ui.control.Label
        MasserrortoleranceLabel   matlab.ui.control.Label
        ListLabel                 matlab.ui.control.Label
        LabraryCheckBox           matlab.ui.control.CheckBox
        UIAxes                    matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % 导入数据
            runcompare=evalin('base','importMSv.summary.runcompare;');
            noFiles=evalin('base','importMSv.load.noFiles;');
            
            if runcompare==2
                app.ListListBox.Enable='on';
                try
                    evalin('base','compare=importMSv.compare;');
                    try
                        evalin('base','different=compare.different;');
                    catch
                    end
                    accuracy=evalin('base','compare.accuracy;');
                    app.RetainEditField.Value=num2str(accuracy);
                    
                    app.RectangleSelectionButton.Enable='on';
                    app.FreeselectionButton.Enable='on';
                    app.CorrespondingdistributionButton.Enable='on';
                    
                    if noFiles == 1
                        app.NoEditField.Editable='off';
                        app.VSNoEditField.Editable='off';
                        app.CompareButton.Enable='off';
                    else
                        app.NoEditField.Editable='on';
                        app.VSNoEditField.Editable='on';
                        app.CompareButton.Enable='on';
                    end
                    
                catch
                end
               
            else
                % 初始化
                for i=1:noFiles
                    MSroi=evalin('base',['importMSv.ROIplotGUI.img_',num2str(i),'.MSroi;']);
                    mascara=evalin('base',['importMSv.ROIplotGUI.img_',num2str(i),'.mascara;']);
                    
                    y=MSroi;
                    re_y = mascara;
                    sum_y = reshape(mascara,[],1);
                    
                    assignin('base','sum_y',sum_y);
                    evalin('base',['compare.Intensity.sum_y',num2str(i),'=sum_y;']);
                    
                    assignin('base','re_y',re_y);
                    evalin('base',['compare.Intensity.re_y',num2str(i),'=re_y;']);
                    
                    assignin('base','y',y);
                    evalin('base',['compare.Intensity.y',num2str(i),'=y;']);
                    evalin('base','compare.libraryvalue=0;');
                    
                    evalin('base','clear sum_y re_y y');
                    evalin('base','importMSv.compare=compare;');
                end
                evalin('base','compare.accuracy=0.0001;');
            end
            %选择框
            for h=1:noFiles
                name=evalin('base',['importMSv.summary.img_',num2str(h),'.name_short;']);
                llistnnc=name;
                llistannc(h)={llistnnc};
            end
            app.ListListBox.Items = llistannc;
            evalin('base','compare.currentFile=1;');
            
            %{
            if noFiles==1
                llistannc(1)={'Image no. 1'};
                app.ListListBox.Items = llistannc;
                evalin('base','compare.currentFile=1;');
            else
                for h=1:noFiles
                    llistnnc=['Image no. ',num2str(h)];
                    llistannc(h)={llistnnc};
                end
                app.ListListBox.Items = llistannc;
                evalin('base','compare.currentFile=1;');
            end
            %}
        end

        % Button pushed function: EnterButton
        function EnterButtonPushed(app, event)
            %更改精度
            mzroi=evalin('base','importMSv.ROIplotGUI.current_mzroi');
            xxx = str2double(app.RetainEditField.Value);
            accuracy=-log10(xxx);
            x = round(mzroi,accuracy);
            
            assignin('base','accuracy',xxx);
            evalin('base','compare.accuracy=accuracy;');
            evalin('base','clear accuracy;');
            
            assignin('base','x',x);
            evalin('base','compare.mz.x=x;');
            evalin('base','clear x;');
            
            evalin('base','importMSv.summary.runcompare=2;');
            app.RectangleSelectionButton.Enable='on';
            app.FreeselectionButton.Enable='on';
            app.CorrespondingdistributionButton.Enable='on';
            app.ListListBox.Enable='on';

            noFiles=evalin('base','importMSv.load.noFiles;');
            if noFiles == 1
                app.NoEditField.Editable='off';
                app.VSNoEditField.Editable='off';
                app.CompareButton.Enable='off';
            else
                app.NoEditField.Editable='on';
                app.VSNoEditField.Editable='on';
                app.CompareButton.Enable='on';
            end
        end

        % Value changed function: ListListBox
        function ListListBoxValueChanged(app, event)
            %列表
            app.ListListBox.ItemsData = 1:length(app.ListListBox.Items);
            currentFile=app.ListListBox.Value;
            
            name_short=evalin('base',['importMSv.summary.img_',num2str(currentFile),'.name_short;']);
            app.Label.Text=name_short;
            
            try
                xx=evalin('base',['compare.mean_Intensity.Supplementx',num2str(currentFile),';']);
                yy=evalin('base',['compare.mean_Intensity.Supplementy',num2str(currentFile),';']);
                Pho=plot(app.UIAxes,xx,yy);
                assignin('base','Pho',Pho);
                evalin('base',['compare.Corresponding.Pho',num2str(currentFile),'=Pho;']);
                evalin('base','clear Pho;');
            catch
                cla(app.UIAxes)
            end
            
            assignin('base','currentFile',currentFile)
            evalin('base','compare.currentFile=currentFile;');
            evalin("base",'clear currentFile;');
        end

        % Button pushed function: RectangleSelectionButton
        function RectangleSelectionButtonPushed(app, event)
            % 矩形选取
            currentFile=evalin('base','compare.currentFile;');
            
            x=evalin("base",'compare.mz.x');
            y=evalin("base",['compare.Intensity.y',num2str(currentFile)]);
            re_y=evalin("base",['compare.Intensity.re_y',num2str(currentFile)]);
            sum_y=evalin("base",['compare.Intensity.sum_y',num2str(currentFile)]);
            
            cidx = Rectangle_select(re_y);
            re_cidx = reshape(cidx,[],1);
            [~,Locb] = ismember(re_cidx,sum_y);
            
            Y=y(Locb,:);
            mean_y = mean(Y);
            list=[x;mean_y]';
            
            [xx,yy]=SupplementZero(x,mean_y);
            
            Pho=plot(app.UIAxes,xx,yy,'LineWidth',2);
            figure('visible','off');
            
            assignin('base','Pho',Pho);
            evalin('base',['compare.Corresponding.Pho',num2str(currentFile),'=Pho;']);
            
            assignin('base','xx',xx);
            evalin('base',['compare.mean_Intensity.Supplementx',num2str(currentFile),'=xx;']);
            
            assignin('base','yy',yy);
            evalin('base',['compare.mean_Intensity.Supplementy',num2str(currentFile),'=yy;']);
            
            assignin('base','mean_y',mean_y);
            evalin('base',['compare.mean_Intensity.mean_y',num2str(currentFile),'=mean_y;']);
            
            assignin('base','list',list);
            evalin('base',['compare.list.list',num2str(currentFile),'=list;']);
            
            evalin('base','clear list mean_y xx yy Pho;');
            evalin('base','importMSv.compare=compare;');
        end

        % Button pushed function: FreeselectionButton
        function FreeselectionButtonPushed(app, event)
            %自由选取
            currentFile=evalin('base','compare.currentFile;');
            
            x=evalin("base",'compare.mz.x');
            y=evalin("base",['compare.Intensity.y',num2str(currentFile)]);
            re_y=evalin("base",['compare.Intensity.re_y',num2str(currentFile)]);
            
            cidx = Free_select(re_y);
            re_cidx = reshape(cidx,[],1);
            Locb = re_cidx == 1;
            
            Y=y(Locb,:);
            mean_y = mean(Y);
            list=[x;mean_y]';
            
            [xx,yy]=SupplementZero(x,mean_y);
            
            Pho=plot(app.UIAxes,xx,yy,'LineWidth',2);
            figure('visible','off');
            
            assignin('base','Pho',Pho);
            evalin('base',['compare.Corresponding.Pho',num2str(currentFile),'=Pho;']);
            
            assignin('base','xx',xx);
            evalin('base',['compare.mean_Intensity.Supplementx',num2str(currentFile),'=xx;']);
            
            assignin('base','yy',yy);
            evalin('base',['compare.mean_Intensity.Supplementy',num2str(currentFile),'=yy;']);
            
            assignin('base','mean_y',mean_y);
            evalin('base',['compare.mean_Intensity.mean_y',num2str(currentFile),'=mean_y;']);
            
            assignin('base','list',list);
            evalin('base',['compare.list.list',num2str(currentFile),'=list;']);
            
            evalin('base','clear list mean_y xx yy Pho;');
            evalin('base','importMSv.compare=compare;');
        end

        % Button pushed function: CorrespondingdistributionButton
        function CorrespondingdistributionButtonPushed(app, event)
            %对应分布
            currentFile=evalin('base','compare.currentFile;');
            x=evalin("base",'compare.mz.x');
            Pho=evalin('base',['compare.Corresponding.Pho',num2str(currentFile),';']);
            
            sizeaChildren=size(Pho.Children,1);
            xtip=zeros(sizeaChildren,1);
            for k=1:size(Pho.Children,1)
                xtip(k)=Pho.Children(k).X;
            end
            [mm,~]=size(xtip);
            
            libraryvalue=evalin('base','importMSv.compare.libraryvalue;');
            if libraryvalue==1
                library=evalin('base','importMSv.library.database;');
                col=evalin('base','importMSv.library.mzcol;');
                matching_results = find_mz_info(xtip, 1, library, col);
                assignin('base','matching_results',matching_results);
                evalin('base','importMSv.compare.matching_results=matching_results;');
            end
            
            %画图
            MSroi=evalin('base',['importMSv.ROIplotGUI.img_',num2str(currentFile),'.MSroi;']);
            x_cut=evalin('base',['importMSv.ROIplotGUI.img_',num2str(currentFile),'.x_cut;']);
            y_cut=evalin('base',['importMSv.ROIplotGUI.img_',num2str(currentFile),'.y_cut;']);
            for ii=1:mm
                a=find(x==xtip(ii,1));
                MM=MSroi(:,a);
                figure,imagesc(reshape(MM,x_cut,y_cut));
                axis tight;
                axis image;
                axis off;
                colormap(flipud(hot));
                colorbar;
                mz=xtip(ii,1);
                title({['m/z = ',num2str(mz)];''});
                set(gca,'FontSize',16);
            end
        end

        % Button pushed function: CompareButton
        function CompareButtonPushed(app, event)
            %数据对比
            No1 = str2double(app.NoEditField.Value);
            No2 = str2double(app.VSNoEditField.Value);
            
            x=evalin("base",'compare.mz.x');
            mean1=evalin("base",['compare.mean_Intensity.mean_y',num2str(No1)]);
            mean2=evalin("base",['compare.mean_Intensity.mean_y',num2str(No2)]);
            
            [Lia1vs2,~] = ismember(mean1,mean2);
            loc_mean1 = find(Lia1vs2 == 0);
            dis_x1=x(:,loc_mean1);
            dis_y1=mean1(:,loc_mean1);
            
            [Lia2vs1,~] = ismember(mean2,mean1);
            loc_mean2 = find(Lia2vs1 == 0);
            dis_x2=x(:,loc_mean2);
            dis_y2=mean2(:,loc_mean2);
            
            list1 = [dis_x1;dis_y1]';
            list1_sort = sortrows(list1,-2);
            list2 = [dis_x2;dis_y2]';
            list2_sort = sortrows(list2,-2);
            list1_2=[x;mean1;mean2]';
            a= find(list1_2(:,2)==list1_2(:,3));
            list1_2(a,:)=[];
            
            % 弹窗
            % 表头
            noFiles=evalin('base','importMSv.load.noFiles;');
            vars='mz';
            for h=1:noFiles
                name=evalin('base',['importMSv.summary.img_',num2str(h),'.name_short;']);
                name=string(name);
                vars=[vars,name];
            end
            
            f=figure('Position',[200,200,500,500]);
            t=uitable('Parent',f,'Position',[10,10,490,450],'Data',list1_2);
            set(t,'ColumnWidth',{100,100,100});
            set(t,'ColumnName',vars);
            set(t,'FontSize',16);
            %
            
            assignin('base','list1',list1);
            evalin('base',['different.list',num2str(No1),'=list1;']);
            evalin('base','clear list1;');
            
            assignin('base','list1_sort',list1_sort);
            evalin('base',['different.list',num2str(No1),'_sort','=list1_sort;']);
            evalin('base','clear list1_sort;');
            
            assignin('base','list2',list2);
            evalin('base',['different.list',num2str(No2),'=list2;']);
            evalin('base','clear list2;');
            
            assignin('base','list2_sort',list2_sort);
            evalin('base',['different.list',num2str(No2),'_sort','=list2_sort;']);
            evalin('base','clear list2_sort;');
            
            assignin('base','list1_2',list1_2);
            evalin('base',['different.list',num2str(No1),'_',num2str(No2),'=list1_2;']);
            evalin('base','clear list1_2;');
            
            evalin('base','compare.different=different;');
            evalin('base','importMSv.compare=compare;');
        end

        % Value changed function: LabraryCheckBox
        function LabraryCheckBoxValueChanged(app, event)
            libraryvalue = app.LabraryCheckBox.Value;
            assignin('base','libraryvalue',libraryvalue);
            evalin('base','importMSv.compare.libraryvalue=libraryvalue;');
            evalin('base','clear libraryvalue;');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ListListBox = uilistbox(app.UIFigure);
            app.ListListBox.Items = {};
            app.ListListBox.ValueChangedFcn = createCallbackFcn(app, @ListListBoxValueChanged, true);
            app.ListListBox.Enable = 'off';
            app.ListListBox.Value = {};
            app.RectangleSelectionButton = uibutton(app.UIFigure, 'push');
            app.RectangleSelectionButton.ButtonPushedFcn = createCallbackFcn(app, @RectangleSelectionButtonPushed, true);
            app.RectangleSelectionButton.Enable = 'off';
            app.RectangleSelectionButton.Text = 'Rectangle Selection';
            app.FreeselectionButton = uibutton(app.UIFigure, 'push');
            app.FreeselectionButton.ButtonPushedFcn = createCallbackFcn(app, @FreeselectionButtonPushed, true);
            app.FreeselectionButton.Enable = 'off';
            app.FreeselectionButton.Text = 'Free selection';
            app.CorrespondingdistributionButton = uibutton(app.UIFigure, 'push');
            app.CorrespondingdistributionButton.ButtonPushedFcn = createCallbackFcn(app, @CorrespondingdistributionButtonPushed, true);
            app.CorrespondingdistributionButton.Enable = 'off';
            app.CorrespondingdistributionButton.Text = 'Corresponding distribution';
            app.AveragespectraofthechosenareaLabel = uilabel(app.UIFigure);
            app.AveragespectraofthechosenareaLabel.Text = 'Average spectra of the chosen area ';
            app.EnterButton = uibutton(app.UIFigure, 'push');
            app.EnterButton.ButtonPushedFcn = createCallbackFcn(app, @EnterButtonPushed, true);
            app.EnterButton.Text = 'Enter';
            app.RetainEditField = uieditfield(app.UIFigure, 'text');
            app.RetainEditField.Value = '0.0001';
            app.NoEditField = uieditfield(app.UIFigure, 'text');
            app.NoEditField.Editable = 'off';
            app.VSNoEditField = uieditfield(app.UIFigure, 'text');
            app.VSNoEditField.Editable = 'off';
            app.CompareButton = uibutton(app.UIFigure, 'push');
            app.CompareButton.ButtonPushedFcn = createCallbackFcn(app, @CompareButtonPushed, true);
            app.CompareButton.Enable = 'off';
            app.CompareButton.Text = 'Compare';
            app.NameLabel = uilabel(app.UIFigure);
            app.NameLabel.Text = 'Name:';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.NoLabel = uilabel(app.UIFigure);
            app.NoLabel.Text = ' No.';
            app.VSNoLabel = uilabel(app.UIFigure);
            app.VSNoLabel.Text = 'VS No.';
            app.MasserrortoleranceLabel = uilabel(app.UIFigure);
            app.MasserrortoleranceLabel.Text = 'Mass error tolerance:';
            app.ListLabel = uilabel(app.UIFigure);
            app.ListLabel.Text = 'List';
            app.LabraryCheckBox = uicheckbox(app.UIFigure);
            app.LabraryCheckBox.ValueChangedFcn = createCallbackFcn(app, @LabraryCheckBoxValueChanged, true);
            app.LabraryCheckBox.Text = 'Labrary?';
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'm/z')
            ylabel(app.UIAxes, 'Intensity')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Runcompare

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end