classdef Runclustering < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        KmeanLabel                 matlab.ui.control.Label
        HowmanycategorieswouldyouliketoclassifyLabel  matlab.ui.control.Label
        Editclassify               matlab.ui.control.EditField
        RunKmeanButton             matlab.ui.control.Button
        MSIdataDropDownLabel       matlab.ui.control.Label
        MSIdataDropDown            matlab.ui.control.DropDown
        Label                      matlab.ui.control.Label
        ElbowButton                matlab.ui.control.Button
        AveragemassspectrumButton  matlab.ui.control.Button
        MethodDropDownLabel        matlab.ui.control.Label
        MethodDropDown             matlab.ui.control.DropDown
        ViewButton                 matlab.ui.control.Button
        UIAxes                     matlab.ui.control.UIAxes
        UIAxes2                    matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            noFiles=evalin('base','importMSv.load.noFiles;');
            for h=1:noFiles
                name=evalin('base',['importMSv.summary.img_',num2str(h),'.name_short;']);
                llistnnc=name;
                llistannc(h)={llistnnc};
            end
            app.MSIdataDropDown.Items=llistannc;
            
            runclustering=evalin('base','importMSv.summary.runclustering;');
            if runclustering==1
                evalin('base','importMSv.kmean.currentFile=1;');
                evalin('base','importMSv.kmean.method=1;');
                for i=1:noFiles
                    evalin('base',['importMSv.kmean.img_',num2str(i),'.tsne.clustering=1;']);
                    evalin('base',['importMSv.kmean.img_',num2str(i),'.pca.clustering=1;']);
                end
                evalin('base','importMSv.summary.runclustering=2;');
            end
            
            cla(app.UIAxes,"reset");
            cla(app.UIAxes2,"reset");
            app.UIAxes.XTick=[];
            app.UIAxes.YTick=[];
            app.Label.Text='';
        end

        % Value changed function: MSIdataDropDown
        function MSIdataDropDownValueChanged(app, event)
            app.MSIdataDropDown.ItemsData = 1:length(app.MSIdataDropDown.Items);
            currentFile=app.MSIdataDropDown.Value;
            
            assignin('base','currentFile',currentFile)
            evalin('base','importMSv.kmean.currentFile=currentFile;');
            evalin("base",'clear currentFile;');
            
            cla(app.UIAxes,"reset");
            cla(app.UIAxes2,"reset");
            app.UIAxes.XTick=[];
            app.UIAxes.YTick=[];
            app.Label.Text='';
        end

        % Value changed function: MethodDropDown
        function MethodDropDownValueChanged(app, event)
            app.MethodDropDown.ItemsData = 1:length(app.MethodDropDown.Items);
            method=app.MethodDropDown.Value;
            
            assignin('base','method',method)
            evalin('base','importMSv.kmean.method=method;');
            evalin("base",'clear method;');
            
            cla(app.UIAxes,"reset");
            cla(app.UIAxes2,"reset");
            app.UIAxes.XTick=[];
            app.UIAxes.YTick=[];
            app.Label.Text='';
        end

        % Button pushed function: ElbowButton
        function ElbowButtonPushed(app, event)
            % 肘部法则
            currentFile=evalin('base','importMSv.kmean.currentFile;');
            partialareaROI=evalin('base',' importMSv.summary.runpartialareaROI;');
            if partialareaROI==2
                MSicut=evalin('base',['importMSv.Partialarea.img_',num2str(currentFile),'.MSicut;']);
                if MSicut==2
                    MSroi=evalin('base',['importMSv.ROIplotGUI.img_',num2str(currentFile),'.ori_MSroi;']);
                else
                    MSroi=evalin('base',['importMSv.ROIplotGUI.img_',num2str(currentFile),'.MSroi;']);
                end
            else
                MSroi=evalin('base',['importMSv.ROIplotGUI.img_',num2str(currentFile),'.MSroi;']);
            end
            
            method=evalin('base','importMSv.kmean.method;');
            if method==1
                % tsne
                runclustering=evalin('base',['importMSv.kmean.img_',num2str(currentFile),'.tsne.clustering;']);
                if runclustering==1
                    Dim_data=tsne(MSroi);
                    assignin('base','Dim_data',Dim_data)
                    evalin('base',['importMSv.kmean.img_',num2str(currentFile),'.tsne.Dim_data=Dim_data;']);
                    evalin("base",'clear Dim_data;');
                    evalin('base',['importMSv.kmean.img_',num2str(currentFile),'.tsne.clustering=2;']);
                else
                    Dim_data=evalin('base',['importMSv.kmean.img_',num2str(currentFile),'.tsne.Dim_data;']);
                end
            else
                %pca
                runclustering=evalin('base',['importMSv.kmean.img_',num2str(currentFile),'.pca.clustering;']);
                if runclustering==1
                    [~,Dim_data]=pca(MSroi);
                    Dim_data=normalize(Dim_data,"center","mean");
                    assignin('base','Dim_data',Dim_data)
                    evalin('base',['importMSv.kmean.img_',num2str(currentFile),'.pca.Dim_data=Dim_data;']);
                    evalin("base",'clear Dim_data;');
                    evalin('base',['importMSv.kmean.img_',num2str(currentFile),'.pca.clustering=2;']);
                else
                    Dim_data=evalin('base',['importMSv.kmean.img_',num2str(currentFile),'.pca.Dim_data;']);
                end
            end
            
            sse=[];
            for k=2:1:20
                [~,~,sumd] = kmeans(Dim_data,k,'Start','plus','Replicates',5);
                sse(k-1,1) = sum(sumd);
            end
            %画图
            n=2:20;
            figure,plot(n,sse);
            xlabel('k');ylabel('sse');title('Elbow method');
        end

        % Button pushed function: RunKmeanButton
        function RunKmeanButtonPushed(app, event)
            %kmean
            class=str2double(app.Editclassify.Value);
            assignin('base','kmean_class',class)
            evalin('base','importMSv.kmean.class=kmean_class;');
            evalin("base",'clear kmean_class;');
            
            currentFile=evalin('base','importMSv.kmean.currentFile;');
            mascara=evalin('base',['importMSv.ROIplotGUI.img_',num2str(currentFile),'.mascara;']);
            name_short=evalin('base',['importMSv.summary.img_',num2str(currentFile),'.name_short;']);
            partialareaROI=evalin('base',' importMSv.summary.runpartialareaROI;');
            
           
            if partialareaROI==2
                MSicut=evalin('base',['importMSv.Partialarea.img_',num2str(currentFile),'.MSicut;']);
                if MSicut==2
                    MSroi=evalin('base',['importMSv.ROIplotGUI.img_',num2str(currentFile),'.ori_MSroi;']);
                else
                    MSroi=evalin('base',['importMSv.ROIplotGUI.img_',num2str(currentFile),'.MSroi;']);
                end
            else
                MSroi=evalin('base',['importMSv.ROIplotGUI.img_',num2str(currentFile),'.MSroi;']);
            end
            
            app.Label.Text=name_short;
            
            method=evalin('base','importMSv.kmean.method;');
            if method==1
                % tsne
                runclustering=evalin('base',['importMSv.kmean.img_',num2str(currentFile),'.tsne.clustering;']);
                if runclustering==1
                    Dim_data=tsne(MSroi);
                    assignin('base','Dim_data',Dim_data)
                    evalin('base',['importMSv.kmean.img_',num2str(currentFile),'.tsne.Dim_data=Dim_data;']);
                    evalin("base",'clear Dim_data;');
                    evalin('base',['importMSv.kmean.img_',num2str(currentFile),'.tsne.clustering=2;']);
                else
                    Dim_data=evalin('base',['importMSv.kmean.img_',num2str(currentFile),'.tsne.Dim_data;']);
                end
            else
                %pca
                runclustering=evalin('base',['importMSv.kmean.img_',num2str(currentFile),'.pca.clustering;']);
                if runclustering==1
                    [~,Dim_data]=pca(MSroi);
                    Dim_data=normalize(Dim_data,"center","mean");
                    assignin('base','Dim_data',Dim_data)
                    evalin('base',['importMSv.kmean.img_',num2str(currentFile),'.pca.Dim_data=Dim_data;']);
                    evalin("base",'clear Dim_data;');
                    evalin('base',['importMSv.kmean.img_',num2str(currentFile),'.pca.clustering=2;']);
                else
                    Dim_data=evalin('base',['importMSv.kmean.img_',num2str(currentFile),'.pca.Dim_data;']);
                end
            end
            
            % 聚类
            [m,n]=size(mascara);
            [cidxn,~] = kmeans(Dim_data,class,'dist','sqeuclidean');
            assignin('base','cidxn',cidxn)
            evalin('base','importMSv.kmean.cidxn=cidxn;');
            evalin("base",'clear cidxn;');
            
            
            % 聚类成像
            if partialareaROI==2
                MSicut=evalin('base',['importMSv.Partialarea.img_',num2str(currentFile),'.MSicut;']);
                if MSicut==2
                    mask=evalin('base',['importMSv.Partialarea.img_',num2str(currentFile),'.mask;']);
                    mask=reshape(mask,[],1);
                    mask(mask==1)=cidxn;
                    cidxnn=mask;
                    imagesc(app.UIAxes,reshape(mask,m,n));
                    assignin('base','cidxn',cidxnn)
                    evalin('base','importMSv.kmean.cidxn=cidxn;');
                    evalin("base",'clear cidxn;');
                    
                    
                    class_name='Class0';
                    for j=1:class
                        name=['Class',num2str(j)] ;
                        name=string(name);
                        class_name=[class_name,name];
                    end
                    class=class+1;
                else
                    imagesc(app.UIAxes,reshape(cidxn,m,n));
                    class_name='Class1';
                    for j=2:class
                        name=['Class',num2str(j)] ;
                        name=string(name);
                        class_name=[class_name,name];
                    end
                end
            else
                imagesc(app.UIAxes,reshape(cidxn,m,n));
                class_name='Class1';
                for j=2:class
                    name=['Class',num2str(j)] ;
                    name=string(name);
                    class_name=[class_name,name];
                end
            end
            
            
            % 类别颜色flipud
            cmap = hsv(class);
            
            xmin=min(Dim_data(:,1));
            xmax=max(Dim_data(:,1));
            ymin=min(Dim_data(:,2));
            ymax=max(Dim_data(:,2));
            
            
            figure('visible','off');
            axis(app.UIAxes,'tight','image','off') ;
            colormap(app.UIAxes,cmap);
            L = line(app.UIAxes,ones(class),ones(class), 'LineWidth',2);
            set(L,{'color'},mat2cell(cmap,ones(1,class),3));
            legend(app.UIAxes,class_name,'FontSize',16,'Location','northeastoutside')
            
            % 可视化成像
            scatter(app.UIAxes2,Dim_data(:,1), Dim_data(:,2), 10, cidxn,'filled','HandleVisibility','off')
            figure('visible','off');
            axis(app.UIAxes2,[xmin xmax ymin ymax]) ;
            colormap(app.UIAxes2,cmap);
            L2 = line(app.UIAxes2,ones(class),ones(class), 'LineWidth',2);
            set(L2,{'color'},mat2cell(cmap,ones(1,class),3));
            legend(app.UIAxes2,class_name,'FontSize',16,'Location','northeastoutside')
            %app.UIAxes2.XTick=[];
            %app.UIAxes2.YTick=[];
        end

        % Button pushed function: AveragemassspectrumButton
        function AveragemassspectrumButtonPushed(app, event)
            currentFile=evalin('base','importMSv.kmean.currentFile;');
            class=evalin('base','importMSv.kmean.class;');
            cidxn=evalin('base','importMSv.kmean.cidxn;');
            MSroi=evalin('base',['importMSv.ROIplotGUI.img_',num2str(currentFile),'.MSroi;']);
            x=evalin("base",'importMSv.ROIplotGUI.current_mzroi');
            xmin=min(x);
            xmax=max(x);
            %ymax=max(mean(MSroi,1));
            mean_ya=[];
            for ii=1:class
                pixelss=cidxn==ii;
                ys=MSroi(pixelss,:);
                mean_ys = mean(ys,1);
                mean_ya=[mean_ya;mean_ys];
                ymaxx(ii)=max(mean_ys);
            end
            ymax=max(ymaxx);
            
            for i=1:class
                %pixels=cidxn==i;
                %y=MSroi(pixels,:);
                mean_y = mean_ya(i,:);
                [xx,yy]=SupplementZero(x,mean_y);
                figure
                plot(xx,yy,'LineWidth',2);
                axis([xmin xmax 0 ymax]);
                title({['Class',num2str(i),' : Average mass spectrum  ']});
            end
        end

        % Button pushed function: ViewButton
        function ViewButtonPushed(app, event)
            Clu_view
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.KmeanLabel = uilabel(app.UIFigure);
            app.KmeanLabel.Text = 'K-mean';
            app.HowmanycategorieswouldyouliketoclassifyLabel = uilabel(app.UIFigure);
            app.HowmanycategorieswouldyouliketoclassifyLabel.Text = 'How many categories would you like to classify?';
            app.Editclassify = uieditfield(app.UIFigure, 'text');
            app.RunKmeanButton = uibutton(app.UIFigure, 'push');
            app.RunKmeanButton.ButtonPushedFcn = createCallbackFcn(app, @RunKmeanButtonPushed, true);
            app.RunKmeanButton.Text = 'Run Kmean';
            app.MSIdataDropDownLabel = uilabel(app.UIFigure);
            app.MSIdataDropDownLabel.Text = 'MSI data';
            app.MSIdataDropDown = uidropdown(app.UIFigure);
            app.MSIdataDropDown.Items = {};
            app.MSIdataDropDown.ValueChangedFcn = createCallbackFcn(app, @MSIdataDropDownValueChanged, true);
            app.MSIdataDropDown.Value = {};
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.ElbowButton = uibutton(app.UIFigure, 'push');
            app.ElbowButton.ButtonPushedFcn = createCallbackFcn(app, @ElbowButtonPushed, true);
            app.ElbowButton.Text = 'Elbow';
            app.AveragemassspectrumButton = uibutton(app.UIFigure, 'push');
            app.AveragemassspectrumButton.ButtonPushedFcn = createCallbackFcn(app, @AveragemassspectrumButtonPushed, true);
            app.AveragemassspectrumButton.Text = 'Average mass spectrum';
            app.MethodDropDownLabel = uilabel(app.UIFigure);
            app.MethodDropDownLabel.Text = 'Method';
            app.MethodDropDown = uidropdown(app.UIFigure);
            app.MethodDropDown.Items = {'t-sne', 'PCA'};
            app.MethodDropDown.ValueChangedFcn = createCallbackFcn(app, @MethodDropDownValueChanged, true);
            app.MethodDropDown.Value = 't-sne';
            app.ViewButton = uibutton(app.UIFigure, 'push');
            app.ViewButton.ButtonPushedFcn = createCallbackFcn(app, @ViewButtonPushed, true);
            app.ViewButton.Text = 'View';
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XTick = [];
            app.UIAxes.XTickLabel = '';
            app.UIAxes.YTick = [];
            app.UIAxes.YTickLabel = '';
            app.UIAxes2 = uiaxes(app.UIFigure);
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Runclustering

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end