classdef RunMSimaging < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        MSidataDropDownLabel        matlab.ui.control.Label
        MSidataDropDown             matlab.ui.control.DropDown
        mzvalueLabel                matlab.ui.control.Label
        Label                       matlab.ui.control.Label
        mzminLabel                  matlab.ui.control.Label
        Label_2                     matlab.ui.control.Label
        maxLabel                    matlab.ui.control.Label
        Label_3                     matlab.ui.control.Label
        mzEditFieldLabel            matlab.ui.control.Label
        mzEditField                 matlab.ui.control.EditField
        GoButton                    matlab.ui.control.Button
        NextButton                  matlab.ui.control.Button
        BackButton                  matlab.ui.control.Button
        MSImagingofsingleionchannelLabel  matlab.ui.control.Label
        StrengthrangeDropDownLabel  matlab.ui.control.Label
        StrengthrangeDropDown       matlab.ui.control.DropDown
        EditField                   matlab.ui.control.EditField
        ValueLabel                  matlab.ui.control.Label
        TheintensityindifferentpixelLabel  matlab.ui.control.Label
        LabraryCheckBox             matlab.ui.control.CheckBox
        UIAxes                      matlab.ui.control.UIAxes
        UIAxes2                     matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            noFiles=evalin('base','importMSv.load.noFiles;');
            for h=1:noFiles
                name=evalin('base',['importMSv.summary.img_',num2str(h),'.name_short;']);
                llistnnc=name;
                llistannc(h)={llistnnc};
            end
            app.MSidataDropDown.Items=llistannc;
            evalin('base','importMSv.MSimaging.currentFile=1;');
            evalin('base','importMSv.MSimaging.NO=1;');
            evalin('base','importMSv.MSimaging.libraryvalue=0;');
            
            currentFile=1;
            x_pixels=evalin('base',['importMSv.ROIplotGUI.img_',num2str(currentFile),'.x_cut;']);
            y_pixels=evalin('base',['importMSv.ROIplotGUI.img_',num2str(currentFile),'.y_cut;']);
            MSroi=evalin('base',['importMSv.ROIplotGUI.img_',num2str(currentFile),'.MSroi;']);
            mzroi=evalin('base','importMSv.ROIplotGUI.current_mzroi');
            maxmz=max(mzroi);
            minmz=min(mzroi);
            
            app.Label.Text=num2str(minmz);
            app.Label_2.Text=num2str(minmz);
            app.Label_3.Text=num2str(maxmz);
            
            evalin('base','importMSv.MSimaging.range_mode=1;');
            evalin('base','importMSv.MSimaging.strength=0;');
            
            % 成像图
            original_data=MSroi(:,1);
            data=set_range(original_data,1,0);
            
            imagesc(app.UIAxes2,reshape(data,x_pixels,y_pixels))
            axis(app.UIAxes2,'tight','image','off') ;
            colormap(app.UIAxes2,flipud(hot));
            colorbar(app.UIAxes2);
            
            %像素点该m/z强度
            plot(app.UIAxes,data,'LineWidth',2);
            xlabel(app.UIAxes,'pixel','FontSize',13);
            ylabel(app.UIAxes,'intensity','FontSize',13);
            figure('visible','off');
        end

        % Value changed function: MSidataDropDown
        function MSidataDropDownValueChanged(app, event)
            app.MSidataDropDown.ItemsData = 1:length(app.MSidataDropDown.Items);
            currentFile=app.MSidataDropDown.Value;
            
            assignin('base','currentFile',currentFile)
            evalin('base','importMSv.MSimaging.currentFile=currentFile;');
            evalin("base",'clear currentFile;');
            
            cla(app.UIAxes,"reset");
            cla(app.UIAxes2,"reset");
            app.UIAxes2.XTick=[];
            app.UIAxes2.YTick=[];
            app.Label.Text='';
        end

        % Value changed function: StrengthrangeDropDown
        function StrengthrangeDropDownValueChanged(app, event)
            app.StrengthrangeDropDown.ItemsData = 1:length(app.StrengthrangeDropDown.Items);
            range_mode = app.StrengthrangeDropDown.Value;
            strength=evalin('base','importMSv.MSimaging.strength;');
            if range_mode==3
                app.EditField.Editable='on';
                app.EditField.Value=num2str(strength);
            else
                app.EditField.Value='';
                app.EditField.Editable='off';
            end
            assignin('base','range_mode',range_mode);
            evalin('base','importMSv.MSimaging.range_mode=range_mode;');
            evalin('base','clear range_mode');
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            strength=str2double(app.EditField.Value) ;
            assignin('base','strength',strength );
            evalin('base','importMSv.MSimaging.strength=strength;');
            evalin('base','clear strength');
        end

        % Button pushed function: GoButton
        function GoButtonPushed(app, event)
            cla(app.UIAxes,"reset");
            cla(app.UIAxes2,"reset");
            app.UIAxes2.XTick=[];
            app.UIAxes2.YTick=[];
            app.Label.Text='';
            
            mz=str2double(app.mzEditField.Value);
            
            currentFile=evalin('base','importMSv.MSimaging.currentFile;');
            x_pixels=evalin('base',['importMSv.ROIplotGUI.img_',num2str(currentFile),'.x_cut;']);
            y_pixels=evalin('base',['importMSv.ROIplotGUI.img_',num2str(currentFile),'.y_cut;']);
            MSroi=evalin('base',['importMSv.ROIplotGUI.img_',num2str(currentFile),'.MSroi;']);
            mzroi=evalin('base','importMSv.ROIplotGUI.current_mzroi');
            
            [~,n]=min(abs(mzroi-mz));
            
            mz_value=mzroi(n);
            app.Label.Text=num2str(mz_value);
            
            libraryvalue=evalin('base','importMSv.MSimaging.libraryvalue;');
            if libraryvalue==1
                library=evalin('base','importMSv.library.database;');
                col=evalin('base','importMSv.library.mzcol;');
                matching_results = find_mz_info(mz_value, 1, library, col);
                assignin('base','matching_results',matching_results);
            end
            
            % 成像图
            % 设置范围
            original_data=MSroi(:,n);
            
            range_mode=evalin('base','importMSv.MSimaging.range_mode;');
            strength=evalin('base','importMSv.MSimaging.strength;');
            data=set_range(original_data,range_mode,strength);
            
            imagesc(app.UIAxes2,reshape(data,x_pixels,y_pixels))
            axis(app.UIAxes2,'tight','image','off') ;
            colormap(app.UIAxes2,flipud(hot));
            colorbar(app.UIAxes2);
            
            %像素点该m/z强度
            plot(app.UIAxes,data,'LineWidth',2);
            xlabel(app.UIAxes,'pixel','FontSize',13);
            ylabel(app.UIAxes,'intensity','FontSize',13);
            figure('visible','off');
            
            assignin('base','NO',n)
            evalin('base','importMSv.MSimaging.NO=NO;');
            evalin("base",'clear NO;');
            
            if n==1
                app.BackButton.Enable='off';
            else
                app.BackButton.Enable='on';
            end
            
            [~,m]=size(mzroi);
            if n==m
                app.NextButton.Enable='off';
            else
                app.NextButton.Enable='on';
            end
            
        end

        % Button pushed function: BackButton
        function BackButtonPushed(app, event)
            cla(app.UIAxes,"reset");
            cla(app.UIAxes2,"reset");
            app.UIAxes2.XTick=[];
            app.UIAxes2.YTick=[];
            app.Label.Text='';
            
            nn=evalin('base','importMSv.MSimaging.NO;');
            n=nn-1;
            
            currentFile=evalin('base','importMSv.MSimaging.currentFile;');
            x_pixels=evalin('base',['importMSv.ROIplotGUI.img_',num2str(currentFile),'.x_cut;']);
            y_pixels=evalin('base',['importMSv.ROIplotGUI.img_',num2str(currentFile),'.y_cut;']);
            MSroi=evalin('base',['importMSv.ROIplotGUI.img_',num2str(currentFile),'.MSroi;']);
            mzroi=evalin('base','importMSv.ROIplotGUI.current_mzroi');
            
            mz_value=mzroi(n);
            app.Label.Text=num2str(mz_value);
            
            libraryvalue=evalin('base','importMSv.MSimaging.libraryvalue;');
            if libraryvalue==1
                library=evalin('base','importMSv.library.database;');
                col=evalin('base','importMSv.library.mzcol;');
                matching_results = find_mz_info(mz_value, 1, library, col);
                assignin('base','matching_results',matching_results);
            end
            
            % 成像图
            % 设置范围
            original_data=MSroi(:,n);
            
            range_mode=evalin('base','importMSv.MSimaging.range_mode;');
            strength=evalin('base','importMSv.MSimaging.strength;');
            data=set_range(original_data,range_mode,strength);
            
            imagesc(app.UIAxes2,reshape(data,x_pixels,y_pixels))
            axis(app.UIAxes2,'tight','image','off') ;
            colormap(app.UIAxes2,flipud(hot));
            colorbar(app.UIAxes2);
            
            %像素点该m/z强度
            plot(app.UIAxes,data,'LineWidth',2);
            xlabel(app.UIAxes,'pixel','FontSize',13);
            ylabel(app.UIAxes,'intensity','FontSize',13);
            figure('visible','off');
            
            assignin('base','NO',n)
            evalin('base','importMSv.MSimaging.NO=NO;');
            evalin("base",'clear NO;');
            
            if n==1
                app.BackButton.Enable='off';
            else
                app.BackButton.Enable='on';
            end
            
            [~,m]=size(mzroi);
            if n==m
                app.NextButton.Enable='off';
            else
                app.NextButton.Enable='on';
            end
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            cla(app.UIAxes,"reset");
            cla(app.UIAxes2,"reset");
            app.UIAxes2.XTick=[];
            app.UIAxes2.YTick=[];
            app.Label.Text='';
            
            nn=evalin('base','importMSv.MSimaging.NO;');
            n=nn+1;
            
            currentFile=evalin('base','importMSv.MSimaging.currentFile;');
            x_pixels=evalin('base',['importMSv.ROIplotGUI.img_',num2str(currentFile),'.x_cut;']);
            y_pixels=evalin('base',['importMSv.ROIplotGUI.img_',num2str(currentFile),'.y_cut;']);
            MSroi=evalin('base',['importMSv.ROIplotGUI.img_',num2str(currentFile),'.MSroi;']);
            mzroi=evalin('base','importMSv.ROIplotGUI.current_mzroi');
            
            mz_value=mzroi(n);
            app.Label.Text=num2str(mz_value);
            
            libraryvalue=evalin('base','importMSv.MSimaging.libraryvalue;');
            if libraryvalue==1
                library=evalin('base','importMSv.library.database;');
                col=evalin('base','importMSv.library.mzcol;');
                matching_results=find_mz_info(mz_value, 1, library, col);
                assignin('base','matching_results',matching_results);
            end
            
            % 成像图
            % 设置范围
            original_data=MSroi(:,n);
            
            range_mode=evalin('base','importMSv.MSimaging.range_mode;');
            strength=evalin('base','importMSv.MSimaging.strength;');
            data=set_range(original_data,range_mode,strength);
            
            imagesc(app.UIAxes2,reshape(data,x_pixels,y_pixels))
            axis(app.UIAxes2,'tight','image','off') ;
            colormap(app.UIAxes2,flipud(hot));
            colorbar(app.UIAxes2);
            
            %像素点该m/z强度
            plot(app.UIAxes,data,'LineWidth',2);
            xlabel(app.UIAxes,'pixel','FontSize',13);
            ylabel(app.UIAxes,'intensity','FontSize',13);
            figure('visible','off');
            
            assignin('base','NO',n)
            evalin('base','importMSv.MSimaging.NO=NO;');
            evalin("base",'clear NO;');
            
            if n==1
                app.BackButton.Enable='off';
            else
                app.BackButton.Enable='on';
            end
            
            [~,m]=size(mzroi);
            if n==m
                app.NextButton.Enable='off';
            else
                app.NextButton.Enable='on';
            end
        end

        % Value changed function: LabraryCheckBox
        function LabraryCheckBoxValueChanged(app, event)
            libraryvalue = app.LabraryCheckBox.Value;
            assignin('base','libraryvalue',libraryvalue);
            evalin('base','importMSv.MSimaging.libraryvalue=libraryvalue;');
            evalin('base','clear libraryvalue;');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.MSidataDropDownLabel = uilabel(app.UIFigure);
            app.MSidataDropDownLabel.Text = 'MSi data';
            app.MSidataDropDown = uidropdown(app.UIFigure);
            app.MSidataDropDown.Items = {};
            app.MSidataDropDown.ValueChangedFcn = createCallbackFcn(app, @MSidataDropDownValueChanged, true);
            app.MSidataDropDown.Value = {};
            app.mzvalueLabel = uilabel(app.UIFigure);
            app.mzvalueLabel.Text = 'm/z value:';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.mzminLabel = uilabel(app.UIFigure);
            app.mzminLabel.Text = 'm/z: min';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '';
            app.maxLabel = uilabel(app.UIFigure);
            app.maxLabel.Text = 'max';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '';
            app.mzEditFieldLabel = uilabel(app.UIFigure);
            app.mzEditFieldLabel.Text = 'm/z';
            app.mzEditField = uieditfield(app.UIFigure, 'text');
            app.GoButton = uibutton(app.UIFigure, 'push');
            app.GoButton.ButtonPushedFcn = createCallbackFcn(app, @GoButtonPushed, true);
            app.GoButton.Text = 'Go';
            app.NextButton = uibutton(app.UIFigure, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Text = 'Next';
            app.BackButton = uibutton(app.UIFigure, 'push');
            app.BackButton.ButtonPushedFcn = createCallbackFcn(app, @BackButtonPushed, true);
            app.BackButton.Enable = 'off';
            app.BackButton.Text = 'Back';
            app.MSImagingofsingleionchannelLabel = uilabel(app.UIFigure);
            app.MSImagingofsingleionchannelLabel.Text = 'MSImaging of single-ion channel ';
            app.StrengthrangeDropDownLabel = uilabel(app.UIFigure);
            app.StrengthrangeDropDownLabel.Text = 'Strength range';
            app.StrengthrangeDropDown = uidropdown(app.UIFigure);
            app.StrengthrangeDropDown.Items = {'default:Median', 'Max', 'Manual'};
            app.StrengthrangeDropDown.ValueChangedFcn = createCallbackFcn(app, @StrengthrangeDropDownValueChanged, true);
            app.StrengthrangeDropDown.Value = 'default:Median';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.EditField.Editable = 'off';
            app.ValueLabel = uilabel(app.UIFigure);
            app.ValueLabel.Text = 'Value:';
            app.TheintensityindifferentpixelLabel = uilabel(app.UIFigure);
            app.TheintensityindifferentpixelLabel.Text = 'The intensity in different pixel';
            app.LabraryCheckBox = uicheckbox(app.UIFigure);
            app.LabraryCheckBox.ValueChangedFcn = createCallbackFcn(app, @LabraryCheckBoxValueChanged, true);
            app.LabraryCheckBox.Text = 'Labrary?';
            app.UIAxes = uiaxes(app.UIFigure);
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.UIAxes2.ZTick = [];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RunMSimaging

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end